classdef DSSynth < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DSSynthApplicationUIFigure  matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        TransferFunctionTab         matlab.ui.container.Tab
        PlantPanel                  matlab.ui.container.Panel
        NumeratorEditFieldLabel     matlab.ui.control.Label
        NumeratorEditField          matlab.ui.control.EditField
        DenominatorEditFieldLabel   matlab.ui.control.Label
        DenominatorEditField        matlab.ui.control.EditField
        ImplementationPanel         matlab.ui.container.Panel
        IntegerSliderLabel          matlab.ui.control.Label
        IntegerSlider               matlab.ui.control.Slider
        FractionalSliderLabel       matlab.ui.control.Label
        FractionalSlider            matlab.ui.control.Slider
        SynthesizeButton_3          matlab.ui.control.Button
        ClearButton_3               matlab.ui.control.Button
        PropertyPanel               matlab.ui.container.Panel
        StabilityCheckBox           matlab.ui.control.CheckBox
        StateSpaceTab               matlab.ui.container.Tab
        ImplementationPanel_2       matlab.ui.container.Panel
        IntegerSlider_2Label        matlab.ui.control.Label
        IntegerSlider_2             matlab.ui.control.Slider
        FractionalSlider_2Label     matlab.ui.control.Label
        FractionalSlider_2          matlab.ui.control.Slider
        PlantPanel_2                matlab.ui.container.Panel
        AEditFieldLabel             matlab.ui.control.Label
        AEditField                  matlab.ui.control.EditField
        BEditFieldLabel             matlab.ui.control.Label
        BEditField                  matlab.ui.control.EditField
        CEditFieldLabel             matlab.ui.control.Label
        CEditField                  matlab.ui.control.EditField
        DEditFieldLabel             matlab.ui.control.Label
        DEditField                  matlab.ui.control.EditField
        InputsEditFieldLabel        matlab.ui.control.Label
        InputsEditField             matlab.ui.control.EditField
        SynthesizeButton_2          matlab.ui.control.Button
        ClearButton_2               matlab.ui.control.Button
        PropertyPanel_2             matlab.ui.container.Panel
        StabilityCheckBox_2         matlab.ui.control.CheckBox
        SafetyCheckBox              matlab.ui.control.CheckBox
    end

    methods (Access = private)

        % Button pushed function: ClearButton_3
        function ClearTransferFunctionCallback(app, event)
            app.NumeratorEditField.Value = '';
            app.DenominatorEditField.Value = '';
            app.IntegerSlider.Value = 0;
            app.FractionalSlider.Value = 0;
            app.StabilityCheckBox.Value = 0;
        end

        % Button pushed function: ClearButton_2
        function ClearStateSpaceCallback(app, event)
            app.IntegerSlider_2.Value = 0;
            app.FractionalSlider_2.Value = 0;
            app.AEditField.Value = '';
            app.BEditField.Value = '';
            app.CEditField.Value = '';
            app.DEditField.Value = '';
            app.InputsEditField.Value = '';
            app.StabilityCheckBox_2.Value = 0;
            app.SafetyCheckBox.Value = 0;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DSSynthApplicationUIFigure = uifigure;
            app.DSSynthApplicationUIFigure.Name = 'DSSynth Application';
            setAutoResize(app, app.DSSynthApplicationUIFigure, true)
            app.TabGroup = uitabgroup(app.DSSynthApplicationUIFigure);
            app.TransferFunctionTab = uitab(app.TabGroup);
            app.TransferFunctionTab.Title = 'Transfer-Function';
            app.TransferFunctionTab.Units = 'pixels';
            app.PlantPanel = uipanel(app.TransferFunctionTab);
            app.PlantPanel.Title = 'Plant';
            app.NumeratorEditFieldLabel = uilabel(app.PlantPanel);
            app.NumeratorEditFieldLabel.Text = 'Numerator';
            app.NumeratorEditField = uieditfield(app.PlantPanel, 'text');
            app.DenominatorEditFieldLabel = uilabel(app.PlantPanel);
            app.DenominatorEditFieldLabel.Text = 'Denominator';
            app.DenominatorEditField = uieditfield(app.PlantPanel, 'text');
            app.ImplementationPanel = uipanel(app.TransferFunctionTab);
            app.ImplementationPanel.Title = 'Implementation';
            app.IntegerSliderLabel = uilabel(app.ImplementationPanel);
            app.IntegerSliderLabel.Text = 'Integer';
            app.IntegerSlider = uislider(app.ImplementationPanel);
            app.IntegerSlider.Limits = [0 32];
            app.IntegerSlider.MajorTicks = [0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32];
            app.FractionalSliderLabel = uilabel(app.ImplementationPanel);
            app.FractionalSliderLabel.Text = 'Fractional';
            app.FractionalSlider = uislider(app.ImplementationPanel);
            app.FractionalSlider.Limits = [0 32];
            app.FractionalSlider.MajorTicks = [0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32];
            app.SynthesizeButton_3 = uibutton(app.TransferFunctionTab, 'push');
            app.SynthesizeButton_3.Text = 'Synthesize';
            app.ClearButton_3 = uibutton(app.TransferFunctionTab, 'push');
            app.ClearButton_3.ButtonPushedFcn = createCallbackFcn(app, @ClearTransferFunctionCallback, true);
            app.ClearButton_3.Text = 'Clear';
            app.PropertyPanel = uipanel(app.TransferFunctionTab);
            app.PropertyPanel.Title = 'Property';
            app.StabilityCheckBox = uicheckbox(app.PropertyPanel);
            app.StabilityCheckBox.Text = 'Stability';
            app.StateSpaceTab = uitab(app.TabGroup);
            app.StateSpaceTab.Title = 'State-Space';
            app.StateSpaceTab.Units = 'pixels';
            app.ImplementationPanel_2 = uipanel(app.StateSpaceTab);
            app.ImplementationPanel_2.Title = 'Implementation';
            app.IntegerSlider_2Label = uilabel(app.ImplementationPanel_2);
            app.IntegerSlider_2Label.Text = 'Integer';
            app.IntegerSlider_2 = uislider(app.ImplementationPanel_2);
            app.IntegerSlider_2.Limits = [0 32];
            app.IntegerSlider_2.MajorTicks = [0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32];
            app.FractionalSlider_2Label = uilabel(app.ImplementationPanel_2);
            app.FractionalSlider_2Label.Text = 'Fractional';
            app.FractionalSlider_2 = uislider(app.ImplementationPanel_2);
            app.FractionalSlider_2.Limits = [0 32];
            app.FractionalSlider_2.MajorTicks = [0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32];
            app.PlantPanel_2 = uipanel(app.StateSpaceTab);
            app.PlantPanel_2.Title = 'Plant';
            app.AEditFieldLabel = uilabel(app.PlantPanel_2);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.PlantPanel_2, 'text');
            app.BEditFieldLabel = uilabel(app.PlantPanel_2);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.PlantPanel_2, 'text');
            app.CEditFieldLabel = uilabel(app.PlantPanel_2);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.PlantPanel_2, 'text');
            app.DEditFieldLabel = uilabel(app.PlantPanel_2);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.PlantPanel_2, 'text');
            app.InputsEditFieldLabel = uilabel(app.PlantPanel_2);
            app.InputsEditFieldLabel.Text = 'Inputs';
            app.InputsEditField = uieditfield(app.PlantPanel_2, 'text');
            app.SynthesizeButton_2 = uibutton(app.StateSpaceTab, 'push');
            app.SynthesizeButton_2.Text = 'Synthesize';
            app.ClearButton_2 = uibutton(app.StateSpaceTab, 'push');
            app.ClearButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearStateSpaceCallback, true);
            app.ClearButton_2.Text = 'Clear';
            app.PropertyPanel_2 = uipanel(app.StateSpaceTab);
            app.PropertyPanel_2.Title = 'Property';
            app.StabilityCheckBox_2 = uicheckbox(app.PropertyPanel_2);
            app.StabilityCheckBox_2.Text = 'Stability';
            app.SafetyCheckBox = uicheckbox(app.PropertyPanel_2);
            app.SafetyCheckBox.Text = 'Safety';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DSSynth()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DSSynthApplicationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DSSynthApplicationUIFigure)
        end
    end
end